classdef NotchedDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NotchedEndoscopeResearchDesignSoftwareUIFigure  matlab.ui.Figure
        NotchedEndoscopeResearchDesignSoftwareLabel  matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        SimulationTab               matlab.ui.container.Tab
        Panel                       matlab.ui.container.Panel
        LoadSTLFileLabel            matlab.ui.control.Label
        LoadFileButton              matlab.ui.control.Button
        TerminateSimulationOutputParametersLabel  matlab.ui.control.Label
        OutputParametersButton      matlab.ui.control.Button
        STLDropDown                 matlab.ui.control.DropDown
        ClearSTLButton              matlab.ui.control.Button
        SimulationStatisticsPanel   matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        AdvancementLabel            matlab.ui.control.Label
        Advancement                 matlab.ui.control.Label
        RotationLabel               matlab.ui.control.Label
        Rotation                    matlab.ui.control.Label
        TendonDisplacementLabel     matlab.ui.control.Label
        TendonDisplacement          matlab.ui.control.Label
        CollisionLabel              matlab.ui.control.Label
        CollisionStateLabel         matlab.ui.control.Label
        mmLabel                     matlab.ui.control.Label
        radiansLabel                matlab.ui.control.Label
        mmLabel_2                   matlab.ui.control.Label
        PlotContainer               matlab.ui.container.Panel
        PlotAxes                    matlab.ui.control.UIAxes
        HeadAxes                    matlab.ui.control.UIAxes
        ErrorPanel                  matlab.ui.container.Panel
        ErrorLabel                  matlab.ui.control.Label
        UserAssistanceOptionsPanel  matlab.ui.container.Panel
        ToggleCollisionDetectionCheckBox  matlab.ui.control.CheckBox
        ToggleHeadVisionCheckBox    matlab.ui.control.CheckBox
        HeadVisionFOVangleLabel     matlab.ui.control.Label
        HeadVisionFOVangleSlider    matlab.ui.control.Slider
        ToggleVisionLineCheckBox    matlab.ui.control.CheckBox
        DrawRayTraceButton          matlab.ui.control.Button
        ClearRayTraceButton         matlab.ui.control.Button
        StartSimulationButton       matlab.ui.control.Button
        ControlMethodDropDownLabel  matlab.ui.control.Label
        ControlMethodDropDown       matlab.ui.control.DropDown
        DesignTab                   matlab.ui.container.Tab
        NotchParametersPanel        matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        AlpharadLabel               matlab.ui.control.Label
        WidthmmLabel                matlab.ui.control.Label
        HeightmmLabel               matlab.ui.control.Label
        SpacingField                matlab.ui.control.NumericEditField
        AlphaField                  matlab.ui.control.NumericEditField
        WidthField                  matlab.ui.control.NumericEditField
        HeightField                 matlab.ui.control.NumericEditField
        SpacingmmLabel              matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        DeleteButton                matlab.ui.control.Button
        SelectNotchLabel            matlab.ui.control.Label
        NotchID                     matlab.ui.control.DropDown
        NotchedPlotContainer        matlab.ui.container.Panel
        NotchedAxes                 matlab.ui.control.UIAxes
        ResetNotchesButton          matlab.ui.control.Button
        ErrorPanel_2                matlab.ui.container.Panel
        ErrorLabel_2                matlab.ui.control.Label
        RobotParametersPanel        matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        InnerDiametermmLabel        matlab.ui.control.Label
        InnerDiameterNumber         matlab.ui.control.Label
        OuterDiametermmLabel        matlab.ui.control.Label
        OuterDiameterNumber         matlab.ui.control.Label
        WPIlogo                     matlab.ui.control.Image
        cometlablogo                matlab.ui.control.Image
    end

    
    properties (Access = public)
        wrist; % Storage for the wrist robot object
        
        % The STL file currently loaded
        stlFileName; % The name of the uploaded .STL file
        stlFilePath; % The path of the uploaded .STL file
        meMesh; % Middle-ear Mesh
        osMesh; % Ossicle Mesh
        totalMesh; % The total mesh of the inner ear
        transform; % Transform from Mesh to Base
        mePatch; % Middle ear STL patch
        osPatch; % Ossicle STL patch
        loadedSTLName;
        
        % Looping Flags
        stopFlag = 0; % Check whether the loop needs to be stopped
        collisionFlag = 0; % Check whether collision detection needs to turn on
        headFlag = 0; % Check whether head projection needs to turn on
        rayFlag = 0; % check whether the ray tracing needs to turn on
        visionLineFlag = 0; % Check whether the head vision line needs to be drawn
        clearRay = 0;
        
        % Graphics Objects
        visionLine; % Controls the vision line coming from the head of the robot
        
        % Robot configuration parameters
        configuration = [0, 0, 0];
        % Three-element array
        % [displacement, rotation, advancement]
        
        % Initial configuration, loaded upon initialization and reset
        robotDisplacement = 0.1;
        robotRotation = 0.0;
        robotAdvancement = 2.0;
        
        % Arduino Controller
        arduinoController; % Arduino Controller object
        % Controls Arduino and Hardware Control Interface, updates and
        % reads the Nunchuk values from the arduino.
        keyBoard % Toggle keyboard mode
        
        startWaitFlag = 0;
    end
    
    methods (Access = private)
        
        % Reset the ID Dropdown tab in the notch editor
        function results = resetDropdown(app)
            counter = 1;
            result = [];
            
            % Loop through through the number of cutouts and add them to
            % the array
            while counter <= app.wrist.nCutouts
                result(counter) = counter;
                counter = counter + 1;
            end
            
            result = [result, "Add"];
            app.NotchID.Items = string(result); % Convert the IDs to strings and set equal to dropdown
        end
        
        % Search in the anatomical-models folder for all STL folders
        function findSTLFolders(app)
            files2 = dir("anatomical-models\");
            files2(1:2) = [];
            dirFlags = [files2.isdir];
            subFolders = files2(dirFlags);
            
            newDrop = {};
            
            % Iterate through all the subfolders and build the new dropdown
            for k = 1 : length(subFolders)
                newDrop(k) = cellstr(subFolders(k).name);
            end
            
            app.STLDropDown.Items = newDrop;
        end
        
        
        function resetCOMDropdown(app)
            COMports = seriallist;
            
            result = ["Keyboard"];
            for i = 1 : size(COMports, 2)
                result(i + 1) = COMports(i);
            end
            
            app.ControlMethodDropDown.Items = string(result);
        end
    end
    
    
    methods (Access = public)
        
        % Display an error in the Simulation UI
        % ME - MATLAB Exception (MException)
        % Display in ErrorPanel and ErrorPanel2
        function printError(app, ME)
            if isempty(ME)
                % Hide the error labels if there is no error
                app.ErrorPanel.Visible = 'off';
            else
                % There is an error, display it
                app.ErrorPanel.Visible = 'on';
                app.ErrorPanel.Title = 'Error';
                app.ErrorLabel.Text = strcat(ME.message);
            end
        end
        
        % Display an error in the Editor UI
        function printErrorEditor(app, ME)
            if isempty(ME)
                app.ErrorPanel_2.Visible = 'off';
            else
                % There is an error, display it
                app.ErrorPanel_2.Visible = 'on';
                app.ErrorPanel_2.Title = 'Error';
                app.ErrorLabel_2.Text = strcat(ME.identifier, ME.message);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ID, OD, nCutouts, cutouts)
            set(app.NotchedEndoscopeResearchDesignSoftwareUIFigure, 'DoubleBuffer','on')
            app.wrist = Wrist(ID, OD, nCutouts, cutouts);
            resetDropdown(app); % Reset the dropdown on start-up
            retrieveNotch(app); % Load the selected notch
            drawNotched(app); % Draw the notched tube in the editor screen
            findSTLFolders(app); % Find the STL folders
            app.OuterDiameterNumber.Text = num2str(app.wrist.OD);
            app.InnerDiameterNumber.Text = num2str(app.wrist.ID);
            app.PlotContainer.Children = flip(app.PlotContainer.Children); % Flip the head view and the main view
            resetCOMDropdown(app)
        end

        % Button pushed function: SaveButton
        function updateNotched(app, event)
            % Update the current notched tube parameters
            
            idValue = str2num(app.NotchID.Value); % Read the Notch ID
            % Notch ID starts at 1 from cannula base and counts up from
            % there
            % Read the notch parameters
            spacingValue = app.SpacingField.Value;
            alphaValue = app.AlphaField.Value;
            widthValue = app.WidthField.Value;
            heightValue = app.HeightField.Value;
            
            % Error flag to check valid user input
            updateError = 0;
            
            if widthValue <= 0 || widthValue > app.wrist.OD
                
                ME = MException('EditorError:InvalidUserInput', ...
                    'Width %d is not valid. Please enter a number \n within the outer dimension of the notched tube',widthValue);
                app.printErrorEditor(ME);
                updateError = 1; % Set error flag
            end
            
            if spacingValue <= 0
                ME = MException('EditorError:InvalidUserInput', ...
                    'Spacing %d is not valid. Please enter a number \n within the outer dimension of the notched tube', ...
                    spacingValue);
                app.printErrorEditor(ME);
                updateError = 1; % Set error flag
            end
            
            if heightValue <= 0
                ME = MException('EditorError:InvalidUserInput', ...
                    'Height %d is not valid. Please enter a number \n within the outer dimension of the notched tube', ...
                    heightValue);
                app.printErrorEditor(ME);
                updateError = 1; % Set error flag
            end
            
            if ~updateError
                % Check if the user wants to add a new notch
                if isempty(idValue)
                    % Create a new notch
                    newCutout.w = [app.wrist.cutouts.w widthValue];
                    newCutout.u = [app.wrist.cutouts.u spacingValue];
                    newCutout.h = [app.wrist.cutouts.h heightValue];
                    newCutout.alpha = [app.wrist.cutouts.alpha alphaValue];
                    
                    % Update app values
                    app.wrist.cutouts = newCutout;
                    app.wrist.nCutouts = app.wrist.nCutouts + 1;
                    resetDropdown(app); % Reset the ID dropdown menu
                    drawNotched(app); % Draw the new notched tube
                    app.NotchID.Value = num2str(app.wrist.nCutouts);
                    retrieveNotch(app);
                    
                    % Debugging print statement
                    disp(newCutout);
                else
                    % ID value is less than the number of cutouts, so update a
                    % previous notch
                    newCutout = app.wrist.cutouts;
                    newCutout.w(1, idValue) = widthValue;
                    newCutout.h(1, idValue) = heightValue;
                    newCutout.u(1, idValue) = spacingValue;
                    newCutout.alpha(1, idValue) = alphaValue;
                    app.wrist.cutouts = newCutout;
                    drawNotched(app);
                    resetDropdown(app);
                    
                    % Debugging print statement
                    disp(newCutout);
                end
                
            end
        end

        % Button pushed function: DeleteButton
        function removeNotch(app, event)
            % Remove a notch from the simulation/editor
            
            idValue = str2num(app.NotchID.Value);
            if idValue <= app.wrist.nCutouts
                % Remove the values
                newCutout = app.wrist.cutouts;
                newCutout.w(idValue) = [];
                newCutout.h(idValue) = [];
                newCutout.alpha(idValue) = [];
                newCutout.u(idValue) = [];
                
                % Update the app values
                app.wrist.cutouts = newCutout;
                app.wrist.nCutouts = app.wrist.nCutouts - 1;
                drawNotched(app);
                resetDropdown(app);
            else
                disp("Invalid ID");
            end
            
        end

        % Button pushed function: LoadFileButton
        function loadSTL(app, event)
            % Load an STL if the Load File button is pressed
            
            delete(app.mePatch);
            delete(app.osPatch);
            
            value = app.STLDropDown.Value;
            app.loadedSTLName = value;
            % fprintf("Loading: %s\n ", value);
            
            if strlength(value) > 0
                % Read the STL using Professor Fichera's code
                [meModel, transform, meModelRayCast, meSegmentation] = loadEarModel(value);
                
                % Store the values from the mesh
                app.transform = transform;
                % app.transform(1,4) = app.transform(1,4) + 10;
                app.meMesh = meModel.me;
                app.osMesh = meModel.os;
                
                % Construct a total mesh used for ray-casting
                points = vertcat(app.meMesh.Vertices, app.osMesh.Vertices);
                faceUpdate = app.osMesh.Faces + max(max(app.meMesh.Faces));
                faces = vertcat(app.meMesh.Faces, faceUpdate);
                
                app.totalMesh.Vertices = points;
                app.totalMesh.Faces = faces;
                
                % Draw the STLs
                app.mePatch = patch(app.PlotAxes, app.meMesh, 'FaceAlpha', 0.5, 'FaceColor', '#2b2e33', ...
                    'PickableParts', 'none', 'BusyAction', 'cancel');
                app.osPatch = patch(app.PlotAxes, app.osMesh, 'LineWidth', 0.2, 'FaceColor', '#071938', ...
                    'PickableParts', 'none', 'BusyAction', 'cancel');
                
                % Reset camera orientation and position and axis limits
                campos(app.PlotAxes, 'auto');
                xlim(app.PlotAxes, 'auto');
                ylim(app.PlotAxes, 'auto');
                zlim(app.PlotAxes, 'auto');
                axis(app.PlotAxes, 'equal');
            else
                disp("Illegal filename: Filename cannot be empty")
            end
            
        end

        % Callback function
        function outputParameters(app, event)
            % TODO: Output all the parameters of the concentric notched
            % tube
        end

        % Button pushed function: OutputParametersButton
        function stopLoop(app, event)
            % Flag to stop the loop for debugging or program termination
            printOutParameters(app);
            app.stopFlag = 1;
        end

        % Key press function: 
        % NotchedEndoscopeResearchDesignSoftwareUIFigure
        function NotchedEndoscopeResearchDesignSoftwareUIFigureKeyPress(app, event)

            % Pass the callback through to another function
            
            % Execute appKeyPressCallBack.m
            key = event.Key;
            switch key
                case 'w'
                    % forward, W key is pressed
                    app.configuration(3) = app.configuration(3) + 0.05;
                case 's'
                    % backward, S key is pressed
                    app.configuration(3) = app.configuration(3) - 0.05;
                case 'a'
                    % left, A key is pressed
                    app.configuration(1) = app.configuration(1) + 0.05;
                case 'd'
                    % right, d key is pressed
                    app.configuration(1) = app.configuration(1) - 0.05;
                case 'q'
                    app.configuration(2) = app.configuration(2) + 0.1;
                case 'e'
                    app.configuration(2) = app.configuration(2) - 0.1;
            end
        end

        % Value changed function: NotchID
        function retrieveNotch(app, event)
            % Retrieve the cutout parameters for a specific notch
            value = str2num(app.NotchID.Value);
            
            if ~isempty(value)
                if value > 0 && value <= app.wrist.nCutouts
                    % Retrieve the values
                    retrievedNotch.w = app.wrist.cutouts.w(1,value);
                    retrievedNotch.u = app.wrist.cutouts.u(1,value);
                    retrievedNotch.h = app.wrist.cutouts.h(1,value);
                    retrievedNotch.alpha = app.wrist.cutouts.alpha(1,value);
                    
                    % Display the values in the app
                    app.SpacingField.Value = retrievedNotch.u;
                    app.AlphaField.Value = retrievedNotch.alpha;
                    app.WidthField.Value = retrievedNotch.w;
                    app.HeightField.Value = retrievedNotch.h;
                else
                    disp("Invalid ID in retrieveNotch")
                end
            end
            drawNotched(app); % Now that the values are retrieved, draw the new notch
        end

        % Button pushed function: ClearSTLButton
        function removeSTL(app, event)
            % Clear the axes
            delete(app.osPatch);
            delete(app.mePatch);
            
            % Reset all the parameters that were set before the load
            app.transform = eye(4);
            app.PlotAxes.XLim = [-15 15];
            app.PlotAxes.YLim = [-15 15];
            app.PlotAxes.ZLim = [0 15];
        end

        % Button pushed function: ResetNotchesButton
        function resetRobot(app, event)
            % Reset the robot configuration to the initial values
            app.configuration = [app.robotDisplacement app.robotRotation app.robotAdvancement]
        
            % Reset the robot to its initial cutout state
            cutouts.w = [1 1 1 1];
            cutouts.u = [1 1 1 1];
            cutouts.h = [1 1 1 1];
            cutouts.alpha = [0 0 pi/2 0];
            
            
            % Update the values and redraw
            app.wrist.cutouts = cutouts;
            drawNotched(app);
            resetDropdown(app);
            app.NotchID.Value = '1';
            retrieveNotch(app);
        end

        % Value changed function: ToggleCollisionDetectionCheckBox
        function collisionToggle(app, event)
            % Toggle collision detection
            value = app.ToggleCollisionDetectionCheckBox.Value;
            app.collisionFlag = value;
        end

        % Value changed function: ToggleHeadVisionCheckBox
        function headVision(app, event)
            % Turn on head vision
            value = app.ToggleHeadVisionCheckBox.Value;
            cla(app.HeadAxes); % Clear the plot
            
            if value
                % User wants head vision
                % Move to foreground
                app.PlotContainer.Children = flip(app.PlotContainer.Children);
                app.HeadAxes.Visible = 'on'; % Show the plot
                app.HeadVisionFOVangleSlider.Visible = 'on'; % Show the FOV Slider
                app.HeadVisionFOVangleLabel.Visible = 'on'; % Show the FOV Label
            else
                % User has turned off head vision
                app.HeadAxes.Visible = 'off'; % Hide the plot
                app.HeadVisionFOVangleSlider.Visible = 'off'; % Hide the FOV Slider
                app.HeadVisionFOVangleLabel.Visible = 'off'; % Hide the FOV Label
                % Move to background
                app.PlotContainer.Children = flip(app.PlotContainer.Children);
            end
        end

        % Button pushed function: DrawRayTraceButton
        function rayTraceToggle(app, event)
            app.rayFlag = 1;
        end

        % Close request function: 
        % NotchedEndoscopeResearchDesignSoftwareUIFigure
        function NotchedEndoscopeResearchDesignSoftwareUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: ToggleVisionLineCheckBox
        function toggleVisionLine(app, event)
            value = app.ToggleVisionLineCheckBox.Value;
            app.visionLineFlag = value;
            
            if value == 0
                delete(app.visionLine); % Delete the graphics object from the screen once untoggled
            end
        end

        % Button pushed function: StartSimulationButton
        function connectArduino(app, event)
            app.keyBoard = 0;
            dropdownValue = app.ControlMethodDropDown.Value;
            
            if strcmp(dropdownValue, "Keyboard")
                app.keyBoard = 1;
                comME = MException("APPCONTROLLER:keyboardEnabled", "Keyboard enabled!\n");
                app.printError(comME);
                app.startWaitFlag = 1;
            else
                
                COMPort = app.ControlMethodDropDown.Value;
                
                % Ensure that the COM port is a number greater than 0
                if ~isempty(COMPort)
                    fprintf("Selected port: %s, Starting Arduino \n", COMPort);
                    try
                        app.arduinoController = arduinoController(COMPort);
                        app.startWaitFlag = 1;
                    catch
                        comME = MException("APPCONTROLLER:keyboardEnabled", "There was an error setting up the arduino\n Keyboard enabled!");
                        app.printError(comME);
                        app.keyBoard = 1;
                        app.startWaitFlag = 1;
                    end
                end
            end
            
        end

        % Value changed function: ControlMethodDropDown
        function updateCOMDropdown(app, event)
            value = app.ControlMethodDropDown.Value;
            resetCOMDropdown(app); % Force an update of the dropdown
        end

        % Button pushed function: ClearRayTraceButton
        function clearRayTrace(app, event)
            app.clearRay = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NotchedEndoscopeResearchDesignSoftwareUIFigure = uifigure('Visible', 'off');
            app.NotchedEndoscopeResearchDesignSoftwareUIFigure.Name = 'Notched Endoscope Research Design Software';
            app.NotchedEndoscopeResearchDesignSoftwareUIFigure.CloseRequestFcn = createCallbackFcn(app, @NotchedEndoscopeResearchDesignSoftwareUIFigureCloseRequest, true);
            app.NotchedEndoscopeResearchDesignSoftwareUIFigure.KeyPressFcn = createCallbackFcn(app, @NotchedEndoscopeResearchDesignSoftwareUIFigureKeyPress, true);
            app.NotchedEndoscopeResearchDesignSoftwareLabel = uilabel(app.NotchedEndoscopeResearchDesignSoftwareUIFigure);
            app.NotchedEndoscopeResearchDesignSoftwareLabel.Text = 'Notched Endoscope Research Design Software';
            app.TabGroup = uitabgroup(app.NotchedEndoscopeResearchDesignSoftwareUIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Panel = uipanel(app.SimulationTab);
            app.LoadSTLFileLabel = uilabel(app.Panel);
            app.LoadSTLFileLabel.Text = 'Load .STL File';
            app.LoadFileButton = uibutton(app.Panel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadSTL, true);
            app.LoadFileButton.Text = 'Load File';
            app.TerminateSimulationOutputParametersLabel = uilabel(app.Panel);
            app.TerminateSimulationOutputParametersLabel.Text = 'Terminate Simulation & Output Parameters';
            app.OutputParametersButton = uibutton(app.Panel, 'push');
            app.OutputParametersButton.ButtonPushedFcn = createCallbackFcn(app, @stopLoop, true);
            app.OutputParametersButton.Text = 'Output Parameters';
            app.STLDropDown = uidropdown(app.Panel);
            app.STLDropDown.Items = {'0'};
            app.STLDropDown.Value = '0';
            app.ClearSTLButton = uibutton(app.Panel, 'push');
            app.ClearSTLButton.ButtonPushedFcn = createCallbackFcn(app, @removeSTL, true);
            app.ClearSTLButton.Text = 'Clear STL';
            app.SimulationStatisticsPanel = uipanel(app.SimulationTab);
            app.SimulationStatisticsPanel.Title = 'Simulation Statistics';
            app.GridLayout2 = uigridlayout(app.SimulationStatisticsPanel);
            app.AdvancementLabel = uilabel(app.GridLayout2);
            app.AdvancementLabel.Text = 'Advancement';
            app.Advancement = uilabel(app.GridLayout2);
            app.Advancement.Text = '0.0';
            app.RotationLabel = uilabel(app.GridLayout2);
            app.RotationLabel.Text = 'Rotation';
            app.Rotation = uilabel(app.GridLayout2);
            app.Rotation.Text = '0.0';
            app.TendonDisplacementLabel = uilabel(app.GridLayout2);
            app.TendonDisplacementLabel.Text = 'Tendon Displacement';
            app.TendonDisplacement = uilabel(app.GridLayout2);
            app.TendonDisplacement.Text = '0.0';
            app.CollisionLabel = uilabel(app.GridLayout2);
            app.CollisionLabel.Text = 'Collision';
            app.CollisionStateLabel = uilabel(app.GridLayout2);
            app.CollisionStateLabel.Text = 'Yes';
            app.mmLabel = uilabel(app.GridLayout2);
            app.mmLabel.Text = 'mm';
            app.radiansLabel = uilabel(app.GridLayout2);
            app.radiansLabel.Text = 'radians';
            app.mmLabel_2 = uilabel(app.GridLayout2);
            app.mmLabel_2.Text = 'mm';
            app.PlotContainer = uipanel(app.SimulationTab);
            app.PlotAxes = uiaxes(app.PlotContainer);
            title(app.PlotAxes, '')
            xlabel(app.PlotAxes, 'X')
            ylabel(app.PlotAxes, 'Y')
            app.PlotAxes.XLim = [-15 15];
            app.PlotAxes.YLim = [-15 15];
            app.PlotAxes.ZLim = [0 15];
            app.HeadAxes = uiaxes(app.PlotContainer);
            title(app.HeadAxes, '')
            xlabel(app.HeadAxes, 'X')
            ylabel(app.HeadAxes, 'Y')
            app.HeadAxes.XTick = [];
            app.HeadAxes.YTick = [];
            app.HeadAxes.Visible = 'off';
            app.ErrorPanel = uipanel(app.SimulationTab);
            app.ErrorPanel.Title = 'Console';
            app.ErrorLabel = uilabel(app.ErrorPanel);
            app.ErrorLabel.Text = '';
            app.UserAssistanceOptionsPanel = uipanel(app.SimulationTab);
            app.UserAssistanceOptionsPanel.Title = 'User Assistance Options';
            app.ToggleCollisionDetectionCheckBox = uicheckbox(app.UserAssistanceOptionsPanel);
            app.ToggleCollisionDetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @collisionToggle, true);
            app.ToggleCollisionDetectionCheckBox.Text = 'Toggle Collision Detection';
            app.ToggleHeadVisionCheckBox = uicheckbox(app.UserAssistanceOptionsPanel);
            app.ToggleHeadVisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @headVision, true);
            app.ToggleHeadVisionCheckBox.Text = 'Toggle Head Vision';
            app.HeadVisionFOVangleLabel = uilabel(app.UserAssistanceOptionsPanel);
            app.HeadVisionFOVangleLabel.Visible = 'off';
            app.HeadVisionFOVangleLabel.Text = {'Head Vision'; ' FOV angle'};
            app.HeadVisionFOVangleSlider = uislider(app.UserAssistanceOptionsPanel);
            app.HeadVisionFOVangleSlider.Limits = [90 120];
            app.HeadVisionFOVangleSlider.MajorTicks = [90 100 110 120];
            app.HeadVisionFOVangleSlider.Visible = 'off';
            app.HeadVisionFOVangleSlider.Value = 90;
            app.ToggleVisionLineCheckBox = uicheckbox(app.UserAssistanceOptionsPanel);
            app.ToggleVisionLineCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggleVisionLine, true);
            app.ToggleVisionLineCheckBox.Text = 'Toggle Vision Line';
            app.DrawRayTraceButton = uibutton(app.UserAssistanceOptionsPanel, 'push');
            app.DrawRayTraceButton.ButtonPushedFcn = createCallbackFcn(app, @rayTraceToggle, true);
            app.DrawRayTraceButton.Text = 'Draw Ray Trace';
            app.ClearRayTraceButton = uibutton(app.UserAssistanceOptionsPanel, 'push');
            app.ClearRayTraceButton.ButtonPushedFcn = createCallbackFcn(app, @clearRayTrace, true);
            app.ClearRayTraceButton.Text = 'Clear Ray Trace';
            app.StartSimulationButton = uibutton(app.SimulationTab, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @connectArduino, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ControlMethodDropDownLabel = uilabel(app.SimulationTab);
            app.ControlMethodDropDownLabel.Text = 'Control Method';
            app.ControlMethodDropDown = uidropdown(app.SimulationTab);
            app.ControlMethodDropDown.Items = {'Keyboard'};
            app.ControlMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @updateCOMDropdown, true);
            app.ControlMethodDropDown.Value = 'Keyboard';
            app.DesignTab = uitab(app.TabGroup);
            app.DesignTab.Title = 'Design';
            app.NotchParametersPanel = uipanel(app.DesignTab);
            app.NotchParametersPanel.Title = 'Notch Parameters';
            app.GridLayout = uigridlayout(app.NotchParametersPanel);
            app.AlpharadLabel = uilabel(app.GridLayout);
            app.AlpharadLabel.Text = 'Alpha (rad):';
            app.WidthmmLabel = uilabel(app.GridLayout);
            app.WidthmmLabel.Text = 'Width (mm):';
            app.HeightmmLabel = uilabel(app.GridLayout);
            app.HeightmmLabel.Text = 'Height (mm):';
            app.SpacingField = uieditfield(app.GridLayout, 'numeric');
            app.AlphaField = uieditfield(app.GridLayout, 'numeric');
            app.WidthField = uieditfield(app.GridLayout, 'numeric');
            app.HeightField = uieditfield(app.GridLayout, 'numeric');
            app.SpacingmmLabel = uilabel(app.GridLayout);
            app.SpacingmmLabel.Text = 'Spacing (mm):';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @updateNotched, true);
            app.SaveButton.Text = 'Save';
            app.DeleteButton = uibutton(app.GridLayout, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @removeNotch, true);
            app.DeleteButton.Text = 'Delete';
            app.SelectNotchLabel = uilabel(app.GridLayout);
            app.SelectNotchLabel.Text = 'Select Notch:';
            app.NotchID = uidropdown(app.GridLayout);
            app.NotchID.Items = {'1', '2', '3', '4'};
            app.NotchID.ValueChangedFcn = createCallbackFcn(app, @retrieveNotch, true);
            app.NotchID.Value = '1';
            app.NotchedPlotContainer = uipanel(app.DesignTab);
            app.NotchedAxes = uiaxes(app.NotchedPlotContainer);
            title(app.NotchedAxes, '')
            xlabel(app.NotchedAxes, 'X')
            ylabel(app.NotchedAxes, 'Y')
            app.NotchedAxes.XLim = [-15 15];
            app.NotchedAxes.YLim = [-15 15];
            app.NotchedAxes.ZLim = [0 15];
            app.ResetNotchesButton = uibutton(app.DesignTab, 'push');
            app.ResetNotchesButton.ButtonPushedFcn = createCallbackFcn(app, @resetRobot, true);
            app.ResetNotchesButton.Text = 'Reset Notches';
            app.ErrorPanel_2 = uipanel(app.DesignTab);
            app.ErrorPanel_2.Title = 'Console';
            app.ErrorLabel_2 = uilabel(app.ErrorPanel_2);
            app.ErrorLabel_2.Text = '';
            app.RobotParametersPanel = uipanel(app.DesignTab);
            app.RobotParametersPanel.Title = 'Robot Parameters';
            app.GridLayout3 = uigridlayout(app.RobotParametersPanel);
            app.InnerDiametermmLabel = uilabel(app.GridLayout3);
            app.InnerDiametermmLabel.Text = 'Inner Diameter (mm):';
            app.InnerDiameterNumber = uilabel(app.GridLayout3);
            app.InnerDiameterNumber.Text = 'Label2';
            app.OuterDiametermmLabel = uilabel(app.GridLayout3);
            app.OuterDiametermmLabel.Text = 'Outer Diameter (mm):';
            app.OuterDiameterNumber = uilabel(app.GridLayout3);
            app.OuterDiameterNumber.Text = 'Label4';
            app.WPIlogo = uiimage(app.NotchedEndoscopeResearchDesignSoftwareUIFigure);
            app.WPIlogo.ImageSource = 'wpi.png';
            app.cometlablogo = uiimage(app.NotchedEndoscopeResearchDesignSoftwareUIFigure);
            app.cometlablogo.ImageSource = 'cometlab.PNG';
            % Show the figure after all components are created
            app.NotchedEndoscopeResearchDesignSoftwareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NotchedDesigner(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NotchedEndoscopeResearchDesignSoftwareUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NotchedEndoscopeResearchDesignSoftwareUIFigure)
        end
    end
end