classdef synthesisapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WristDesignerUIFigure          matlab.ui.Figure
        VirtualAnatomyPanel            matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        STLDropDown                    matlab.ui.control.DropDown
        LoadModelButton                matlab.ui.control.Button
        EraseModelButton               matlab.ui.control.Button
        EnableRayCastingButton         matlab.ui.control.StateButton
        UIAxes                         matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        CurveDesignTab                 matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        ArcLengthmmSliderLabel         matlab.ui.control.Label
        ArcLengthmmSlider              matlab.ui.control.Slider
        BendingRadiusmmSliderLabel     matlab.ui.control.Label
        BendingRadiusmmSlider          matlab.ui.control.Slider
        Torsion1mSliderLabel           matlab.ui.control.Label
        Torsion1mSlider                matlab.ui.control.Slider
        SelectcurveDropDown            matlab.ui.control.DropDown
        DisplayTubeButton              matlab.ui.control.StateButton
        DeleteCurrentCurveButton       matlab.ui.control.Button
        TubeThicknessmmEditFieldLabel  matlab.ui.control.Label
        TubeThicknessmmEditField       matlab.ui.control.NumericEditField
        RotationdegreesSliderLabel     matlab.ui.control.Label
        AddnewsegmenttoButtonGroup     matlab.ui.container.ButtonGroup
        DistaltipButton                matlab.ui.control.RadioButton
        ProximalendButton              matlab.ui.control.RadioButton
        RotationdegreesSlider          matlab.ui.control.Slider
        NobendingCheckBox              matlab.ui.control.CheckBox
        WristSynthesisTab              matlab.ui.container.Tab
        SaveCurveParametersButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        % The following properties store the curve designed by the user
        curves;       % List of individual curves - each of these is returned by the `makecurve' function
        curvesCount;  % Total number of curves - integer
        currentCurve; % Curve currently selected by the user - integer
        curveT;       % H.T. matrices for each curve
        entireCurve;  % All the curves stacked together - 3xn array
        curveParams;  % nx4 array - each row is: [arcLength, curvature, torsion, base rotation]
        noBending;    % array of boolean indicating if any curve has zero curvature
        
        meMesh; % Anatomical Model of the middle ear
        osMesh; % Anatomical Model of the ossicles
        meSegmentation; % Middle Ear segmentation into subregions (!WARNING this may not be available for all models)
        seenMap;

        meModelLoaded; % Is an anatomical model being displayed? - boolean
        tubeDisplayOn; % Is the tube being displayed? - boolean
        rayCastingOn; % Is ray casting enabled?
        appendToTip;  % Boolean controlling if new segments are appended to tip or base
        
        plotXLimits;
        plotYLimits;
        plotZLimits;
        col; % set of distinguishable colors
        
        availableModels; % List of available stl anatomical models - cell array of strings
        %Tee_in_base;  % Transformation between origin of the curve and end effector
        TCT_in_base;  % Transformation between the origin of the curve and the space of the anatomical model
        
        % tip_base; %!FIXME for debug only. to be removed
        % entry_point; %FIXME for debug only. to be removed
        
        
    end
    
    methods (Access = private)
        
        function updateSliders(app)
            % Get the parameters of the curve currently selected
            params = app.curveParams(app.currentCurve,:);
            
            % Update the sliders
            app.ArcLengthmmSlider.Value = params(1) * 1e3;
            app.Torsion1mSlider.Value = params(3);
            app.RotationdegreesSlider.Value = params(4);
            app.BendingRadiusmmSlider.Value = 1 / params(2) * 1e3;
            app.noBending = app.NobendingCheckBox.Value;
        end
        
        function editCurve(app)
            % This function updates a curve based on the parameters set by
            % the user
            arcLength = app.ArcLengthmmSlider.Value * 1e-3;
            
            if app.NobendingCheckBox.Value == true
                curvature = 0;
            else
                curvature = 1 / (app.BendingRadiusmmSlider.Value * 1e-3);
            end
            
            curvatureFcn = @(s,arcLength) curvature .* ones(1,length(s));
            
            torsion = app.Torsion1mSlider.Value;
            torsionFcn = @(s,arcLength) torsion .* ones(1,length(s));
            
            baseRotation = app.RotationdegreesSlider.Value;
            baseTransform = [rotz(baseRotation, 'deg'), [0 0 0]'; 0 0 0 1];
            
            app.curves{app.currentCurve} = makecurve(arcLength, curvatureFcn, torsionFcn, baseTransform);
            app.curveParams(app.currentCurve,:) = [arcLength, curvature, torsion, baseRotation];
        end
        
        function addCurve(app)
            % This function creates a curve based on the parameters set by
            % the user and updates app.curvesCount and app.currentCurve
            % accordingly
            app.curvesCount = app.curvesCount + 1;
            
            arcLength = app.ArcLengthmmSlider.Value * 1e-3;
            
            curvature = 1 / (app.BendingRadiusmmSlider.Value * 1e-3);
            curvatureFcn = @(s,arcLength) curvature .* ones(1,length(s));
            
            torsion = app.Torsion1mSlider.Value;
            torsionFcn = @(s,arcLength) torsion .* ones(1,length(s));
            
            baseRotation = app.RotationdegreesSlider.Value;
            baseTransform = [rotz(baseRotation, 'deg'), [0 0 0]'; 0 0 0 1];
            
            if app.appendToTip
                app.curves{app.curvesCount} = makecurve(arcLength, curvatureFcn, torsionFcn, baseTransform);
                app.curveParams(app.curvesCount,:) = [arcLength, curvature, torsion, baseRotation];
                
                app.currentCurve = app.curvesCount;
            else
                for ii = fliplr(1 : app.curvesCount - 1)
                    app.curves{ii+1} = app.curves{ii};
                    app.curveParams(ii+1,:) = app.curveParams(ii,:);
                end
                
                app.curves{1} = makecurve(arcLength, curvatureFcn, torsionFcn, baseTransform);
                app.curveParams(1,:) = [arcLength, curvature, torsion, baseRotation];
                
                app.currentCurve = 1;
            end
            
            app.noBending(app.currentCurve) = false;
        end
        
        function deleteCurve(app)
            % This function deletes the curve selected by the user
            % and updates app.curvesCount and app.currentCurve
            % accordingly
            for ii = fliplr(app.currentCurve : app.curvesCount)
                app.curves{ii} = [];
                app.curveParams(ii,:) = [];
                app.noBending(ii) = false;
            end
            
            app.curvesCount = app.currentCurve - 1;
            app.currentCurve = app.curvesCount;
        end
        
        function initializeAnatomyModels(app)
            app.availableModels = {'atlas', '175', '176', '179', '183', '184'};
            app.STLDropDown.Items = app.availableModels;
            app.meModelLoaded = false;
        end
        
        function generateDrawing(app)
            f = app.WristDesignerUIFigure;
            d = uiprogressdlg(f, 'Title', 'Please Wait', ...
                'Message', 'Rendering in progress...', 'Indeterminate', 'on');
                        
            % Generate the `entireCurve' by stacking together all the
            % individual curves
            app.entireCurve = zeros(3,1);
            app.curveT = zeros(4,4,app.curvesCount);
            
            for ii = 1 : app.curvesCount
                if ii == 1, T = app.TCT_in_base;
                else, T = app.curveT(:,:,ii-1); end
                
                n = app.curves{ii}.n(:,end);
                b = app.curves{ii}.b(:,end);
                t = app.curves{ii}.t(:,end);
                
                app.curveT(:,:,ii) = T * [n, b, t, app.curves{ii}.arc(:,end);
                                          0 0 0 1];
                
                temp = applytransform(app.curves{ii}.arc, T);
                app.entireCurve = [app.entireCurve temp];
            end
            
            app.entireCurve = app.entireCurve(:,2:end);
            
            % Generate axis limits for the plot and coloring for the mesh
            if app.meModelLoaded
                app.meMesh.FaceVertexCData = ones(size(app.meMesh.Vertices, 1), 1);
                app.meMesh.LineStyle = 'none';
                app.meMesh.FaceColor = 'flat';
                app.meMesh.FaceAlpha = 0.4 ;
                
                app.osMesh.FaceVertexCData = ones(size(app.osMesh.Vertices, 1), 1);
                app.osMesh.FaceColor = 'flat';
                app.osMesh.FaceAlpha = 0.4 ;
                
                if app.rayCastingOn
%                     osFaces = app.osMesh.Faces + length(app.meMesh.Vertices);
%                     tempMesh.Faces = [app.meMesh.Faces; osFaces];
%                     tempMesh.Vertices = [app.meMesh.Vertices; app.osMesh.Vertices];
                    
                    app.seenMap = app.seenMap | visibilitymap(app.entireCurve(:,end), app.curveT(1:3,3,end), app.meMesh, app.osMesh);
                    colorMap = ones(length(app.meMesh.Vertices), 1);
                    colorMap(logical(app.seenMap)) = 5;
                    app.meMesh.FaceVertexCData = colorMap(1:length(app.meMesh.Vertices));
                    
                    visibility = area_stat(app.meSegmentation(:), app.seenMap(:));
                    disp('Visibility results per region:');
                    disp(visibility);
                end
                
                app.plotXLimits = [min(app.meMesh.Vertices(:,1)) max(app.meMesh.Vertices(:,1))];
                app.plotYLimits = [min(app.meMesh.Vertices(:,2)) max(app.meMesh.Vertices(:,2))];
                app.plotZLimits = [min(app.meMesh.Vertices(:,3)) max(app.meMesh.Vertices(:,3))];
            else
                app.plotXLimits = [min([-10e-3 min(app.entireCurve(1,:))]), max([10e-3 max(app.entireCurve(1,:))])];
                app.plotYLimits = [min([-10e-3 min(app.entireCurve(2,:))]), max([10e-3 max(app.entireCurve(2,:))])];
                app.plotZLimits = [min([0 min(app.entireCurve(3,:))]), max([10e-3 max(app.entireCurve(3,:))])];
            end
            
            % Close the waitbar
            close(d);
            
            app.draw();
            
        end
        
        function draw(app)
            ax = app.UIAxes;
            [az,el] = view(ax);
            
            % Plot each curve section using a different color
            jj = 1;
            for ii = 1 : app.curvesCount
                c = app.entireCurve(:,jj:jj+length(app.curves{ii}.arc)-1);
                plot3(ax, c(1,:), c(2,:), c(3,:), 'Color', app.col(ii,:), 'LineWidth', 2.5);
                hold(ax, 'on');
                jj = jj + length(app.curves{ii}.arc);
            end
            
            if app.tubeDisplayOn
                radiusVec = app.TubeThicknessmmEditField.Value * 1e-3 / 2 * ones(1, size(app.entireCurve, 2));
                [X,Y,Z] = gencyl(app.entireCurve, radiusVec, 2, 20);
                surf(ax, X, Y, Z, 'FaceColor',app.col(end,:));
            end
            
            if app.meModelLoaded
                patch(ax, app.meMesh);
                patch(ax, app.osMesh);
                
                %!FIXME the following instruections are here only for debug
                %purposes
                %                 quiver3(ax, app.entireCurve(1,end), app.entireCurve(2,end), app.entireCurve(3,end), ...
                %                     app.curveT(1,3,end), app.curveT(2,3,end), app.curveT(3,3,end));
                %
                %                 quiver3(ax, app.entireCurve(1,end), app.entireCurve(2,end), app.entireCurve(3,end), ...
                %                     app.curveT(1,2,end), app.curveT(2,2,end), app.curveT(3,2,end));
                %
                %                 quiver3(ax, app.entireCurve(1,end), app.entireCurve(2,end), app.entireCurve(3,end), ...
                %                     app.curveT(1,1,end), app.curveT(2,1,end), app.curveT(3,1,end));
                %scatter3(ax, app.entry_point(1), app.entry_point(2), app.entry_point(3)); %!FIXME for debug only. to be removed
                %scatter3(ax, app.tip_base(1), app.tip_base(2), app.tip_base(3)); %!FIXME for debug only. to be removed
            end
            
            % Add axis labels and limits
            axis(ax, 'equal'), grid(ax, 'on');
            xlim(ax, app.plotXLimits), ylim(ax, app.plotYLimits), zlim(ax, app.plotZLimits);
            xlabel(ax, 'X [m]'), ylabel(ax, 'Y [m]'), zlabel(ax, 'Z [m]');
            title(ax, '');
            view(ax, az, el);
            hold(ax, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create the first curve
            app.appendToTip = true;
            app.curvesCount = 0;
            app.addCurve();
            
            % Populate the list of available anatomy models
            app.initializeAnatomyModels();
            
            % Initialize drawing
            app.col = distinguishable_colors(50);
            app.TCT_in_base = eye(4);
            app.generateDrawing();
        end

        % Value changed function: SelectcurveDropDown
        function SelectcurveDropDownValueChanged(app, event)
            % Invokes the addCurve function and updates the dropdown menu
            % accordingly
            value = app.SelectcurveDropDown.Value;
            
            if strcmp(value, 'Add')
                app.addCurve(); % this updates `app.currentCurve' and `app.curves'
                
                if app.appendToTip
                    app.SelectcurveDropDown.Items{end} = num2str(app.currentCurve);
                    app.SelectcurveDropDown.Items{end+1} = 'Add';
                    app.SelectcurveDropDown.Value = num2str(app.currentCurve);
                else
                    for ii = 1: app.curvesCount
                        app.SelectcurveDropDown.Items{ii} = num2str(ii);
                    end
                    
                    app.SelectcurveDropDown.Items{end+1} = 'Add';
                    app.SelectcurveDropDown.Value = '1';
                end

                % Now update the drawing
                app.generateDrawing();
            else
                app.currentCurve = str2num(value);
            end
            
            % Now update the sliders
            app.updateSliders();
        end

        % Button pushed function: DeleteCurrentCurveButton
        function DeleteCurrentCurveButtonPushed(app, event)
            
            if app.currentCurve == 1
               f = app.WristDesignerUIFigure;
               uialert(f,'The first curve cannot be eliminated','Invalid Action');
               return;
            end
            
            app.deleteCurve(); % this updates `app.currentCurve' and `app.curves'

            % Update the list of available curves in the dropdown menu
            app.SelectcurveDropDown.Items = {};
            
            for ii = 1: app.currentCurve
                app.SelectcurveDropDown.Items{ii} = num2str(ii);
            end

            app.SelectcurveDropDown.Items{end+1} = 'Add';
            app.SelectcurveDropDown.Value = num2str(app.currentCurve);
            
            % Now update the sliders
            app.updateSliders();
            
            % Now update the drawing
            app.generateDrawing();
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            
            modelID = app.STLDropDown.Value;
            
            try
                % Read the configuration file to extract information about the
                % meshes
                fid = fopen(fullfile('anatomical-models', 'configurations.txt'));
                text = textscan(fid, '%s %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f');
                fclose(fid);
                
                configurations = cell2mat(text(2:end));
                line_no = find(strcmp(text{1}, modelID));
                
                %path       = fullfile('meshes', text{1}(line_no));
                %path       = path{:}; % converts cell to char
                %image_size   = configurations(line_no, 1:3);
                voxel_size   = configurations(line_no, 4:6);
                entry_point  = configurations(line_no, 7:9);
                tip_base     = configurations(line_no, 10:12);
                %target_point = configurations(line_no, 13:15);
                
                % Read the Raw Meshes from file
                pathMe = fullfile('anatomical-models', modelID, 'me.mesh');
                pathOs = fullfile('anatomical-models', modelID, 'ossicle.mesh');
                rawMeMesh = meshread(pathMe);
                rawOsMesh = meshread(pathOs);
                
                % Convert the raw meshes into objects that can be passed
                % to the `patch' function
                app.meMesh.Faces = rawMeMesh.triangles' + 1;
                app.meMesh.Vertices = bsxfun(@times, rawMeMesh.vertices', voxel_size);
                app.meMesh.Vertices = app.meMesh.Vertices .* 1e-3;
                
                app.osMesh.Faces = rawOsMesh.triangles' + 1;
                app.osMesh.Vertices = bsxfun(@times, rawOsMesh.vertices', voxel_size);
                app.osMesh.Vertices = app.osMesh.Vertices .* 1e-3;
                
                % Set the transformation between curve and model space
                % app.tip_base = tip_base .* 1e-3; %!FIXME for debug only. to be removed
                % app.entry_point = entry_point .* 1e-3; %!FIXME for debug only. to be removed
                newZ = tip_base .* 1e-3 - entry_point .* 1e-3;
                newZ = newZ ./ norm(newZ);
                v = cross([0 0 1], newZ);
                R = eye(3) + skew(v) + skew(v)^2 * (1-dot([0 0 1], newZ))/norm(v)^2;
                t = entry_point .* 1e-3;
                app.TCT_in_base = [R t'; 0 0 0 1];
                
                % Now load the segmentation
                load(fullfile('anatomical-models', modelID, 'record.mat'));
                app.meSegmentation = recordnew;
                
                % Let the app know that we have loaded an anatomical model
                app.meModelLoaded = true;
                
            catch exception
                f = app.WristDesignerUIFigure;
                uialert(f, exception.message, 'Failed to Read Mesh');
            end
            
            % Now update the drawing
            app.generateDrawing();
        end

        % Button pushed function: EraseModelButton
        function EraseModelButtonPushed(app, event)
            if app.meModelLoaded == false
                f = app.WristDesignerUIFigure;
                uialert(f, 'No anatomy models is currently loaded.', 'Invalid Action');
                return;
            end
            
            % Erase the model
            app.meMesh = [];
            app.osMesh = [];
            app.meSegmentation = [];
            
            % Set the transformation between curve and model space back to
            % the identity matrix
            app.TCT_in_base = eye(4);
            
            % Let the app know that we have removed the model
            app.meModelLoaded = false;
            
            % Now update the drawing
            app.generateDrawing();
        end

        % Value changed function: ArcLengthmmSlider
        function ArcLengthmmSliderValueChanged(app, event)
            app.editCurve();
            app.generateDrawing();
        end

        % Value changed function: BendingRadiusmmSlider
        function BendingRadiusmmSliderValueChanged(app, event)
            app.editCurve();
            app.generateDrawing();
        end

        % Value changed function: Torsion1mSlider
        function Torsion1mSliderValueChanged(app, event)
            app.editCurve();
            app.generateDrawing();
        end

        % Value changed function: RotationdegreesSlider
        function RotationdegreesSliderValueChanged(app, event)
            app.editCurve();
            app.generateDrawing();
        end

        % Value changed function: DisplayTubeButton
        function DisplayTubeButtonValueChanged(app, event)
            app.tubeDisplayOn = app.DisplayTubeButton.Value;
            app.generateDrawing();
        end

        % Value changed function: EnableRayCastingButton
        function EnableRayCastingButtonValueChanged(app, event)
            app.rayCastingOn = app.EnableRayCastingButton.Value;
            
            if app.rayCastingOn && app.meModelLoaded == false
                f = app.WristDesignerUIFigure;
                uialert(f, 'Cannot run ray casting. Load an anatomical model first.', 'Invalid Action');
                app.EnableRayCastingButton.Value = false;
                app.rayCastingOn = false;
                return;
            end
            
            if app.rayCastingOn
                app.seenMap = zeros(1, length(app.meMesh.Vertices));
            else
                app.seenMap = [];
            end
            
            app.generateDrawing();
        end

        % Button pushed function: SaveCurveParametersButton
        function SaveCurveParametersButtonPushed(app, event)
            curveParams = app.curveParams;
            save 'curve_parameters.mat' curveParams -mat
        end

        % Selection changed function: AddnewsegmenttoButtonGroup
        function AddnewsegmenttoButtonGroupSelectionChanged(app, event)
            selectedButton = app.AddnewsegmenttoButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text, 'Distal tip')
                app.appendToTip = true;
            else
                app.appendToTip = false;
            end
            
        end

        % Value changed function: NobendingCheckBox
        function NobendingCheckBoxValueChanged(app, event)
            app.noBending(app.currentCurve) = app.NobendingCheckBox.Value;
                      
            app.editCurve();
            app.generateDrawing();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WristDesignerUIFigure = uifigure('Visible', 'off');
            app.WristDesignerUIFigure.Name = 'Wrist Designer';
            app.VirtualAnatomyPanel = uipanel(app.WristDesignerUIFigure);
            app.VirtualAnatomyPanel.Title = 'Virtual Anatomy';
            app.GridLayout = uigridlayout(app.VirtualAnatomyPanel);
            app.STLDropDown = uidropdown(app.GridLayout);
            app.LoadModelButton = uibutton(app.GridLayout, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.EraseModelButton = uibutton(app.GridLayout, 'push');
            app.EraseModelButton.ButtonPushedFcn = createCallbackFcn(app, @EraseModelButtonPushed, true);
            app.EraseModelButton.Text = 'Erase Model';
            app.EnableRayCastingButton = uibutton(app.GridLayout, 'state');
            app.EnableRayCastingButton.ValueChangedFcn = createCallbackFcn(app, @EnableRayCastingButtonValueChanged, true);
            app.EnableRayCastingButton.Text = 'Enable Ray Casting';
            app.UIAxes = uiaxes(app.WristDesignerUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TabGroup = uitabgroup(app.WristDesignerUIFigure);
            app.CurveDesignTab = uitab(app.TabGroup);
            app.CurveDesignTab.Title = 'Curve Design';
            app.GridLayout2 = uigridlayout(app.CurveDesignTab);
            app.ArcLengthmmSliderLabel = uilabel(app.GridLayout2);
            app.ArcLengthmmSliderLabel.Text = 'Arc Length (mm)';
            app.ArcLengthmmSlider = uislider(app.GridLayout2);
            app.ArcLengthmmSlider.Limits = [1 30];
            app.ArcLengthmmSlider.MajorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.ArcLengthmmSlider.ValueChangedFcn = createCallbackFcn(app, @ArcLengthmmSliderValueChanged, true);
            app.ArcLengthmmSlider.Value = 1;
            app.BendingRadiusmmSliderLabel = uilabel(app.GridLayout2);
            app.BendingRadiusmmSliderLabel.Text = 'Bending Radius (mm)';
            app.BendingRadiusmmSlider = uislider(app.GridLayout2);
            app.BendingRadiusmmSlider.Limits = [10 60];
            app.BendingRadiusmmSlider.ValueChangedFcn = createCallbackFcn(app, @BendingRadiusmmSliderValueChanged, true);
            app.BendingRadiusmmSlider.Value = 60;
            app.Torsion1mSliderLabel = uilabel(app.GridLayout2);
            app.Torsion1mSliderLabel.Text = 'Torsion (1/m)';
            app.Torsion1mSlider = uislider(app.GridLayout2);
            app.Torsion1mSlider.Limits = [0 1000];
            app.Torsion1mSlider.ValueChangedFcn = createCallbackFcn(app, @Torsion1mSliderValueChanged, true);
            app.SelectcurveDropDown = uidropdown(app.GridLayout2);
            app.SelectcurveDropDown.Items = {'1', 'Add'};
            app.SelectcurveDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectcurveDropDownValueChanged, true);
            app.SelectcurveDropDown.Value = '1';
            app.DisplayTubeButton = uibutton(app.GridLayout2, 'state');
            app.DisplayTubeButton.ValueChangedFcn = createCallbackFcn(app, @DisplayTubeButtonValueChanged, true);
            app.DisplayTubeButton.Text = 'Display Tube';
            app.DeleteCurrentCurveButton = uibutton(app.GridLayout2, 'push');
            app.DeleteCurrentCurveButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentCurveButtonPushed, true);
            app.DeleteCurrentCurveButton.Text = 'Delete Current Curve';
            app.TubeThicknessmmEditFieldLabel = uilabel(app.GridLayout2);
            app.TubeThicknessmmEditFieldLabel.Text = 'Tube Thickness (mm)';
            app.TubeThicknessmmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TubeThicknessmmEditField.Value = 1.4;
            app.RotationdegreesSliderLabel = uilabel(app.GridLayout2);
            app.RotationdegreesSliderLabel.Text = 'Rotation (degrees)';
            app.RotationdegreesSlider = uislider(app.GridLayout2);
            app.RotationdegreesSlider.Limits = [0 360];
            app.RotationdegreesSlider.MajorTicks = [0 90 180 270 360];
            app.RotationdegreesSlider.ValueChangedFcn = createCallbackFcn(app, @RotationdegreesSliderValueChanged, true);
            app.AddnewsegmenttoButtonGroup = uibuttongroup(app.GridLayout2);
            app.AddnewsegmenttoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AddnewsegmenttoButtonGroupSelectionChanged, true);
            app.AddnewsegmenttoButtonGroup.Title = 'Add new segment to';
            app.DistaltipButton = uiradiobutton(app.AddnewsegmenttoButtonGroup);
            app.DistaltipButton.Text = 'Distal tip';
            app.DistaltipButton.Value = true;
            app.ProximalendButton = uiradiobutton(app.AddnewsegmenttoButtonGroup);
            app.ProximalendButton.Text = 'Proximal end';
            app.NobendingCheckBox = uicheckbox(app.GridLayout2);
            app.NobendingCheckBox.ValueChangedFcn = createCallbackFcn(app, @NobendingCheckBoxValueChanged, true);
            app.NobendingCheckBox.Text = 'No bending';
            app.WristSynthesisTab = uitab(app.TabGroup);
            app.WristSynthesisTab.Title = 'Wrist Synthesis';
            app.SaveCurveParametersButton = uibutton(app.WristSynthesisTab, 'push');
            app.SaveCurveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurveParametersButtonPushed, true);
            app.SaveCurveParametersButton.Text = 'Save Curve Parameters';
            % Show the figure after all components are created
            app.WristDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synthesisapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WristDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WristDesignerUIFigure)
        end
    end
end